classdef qualisysMatViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        MenuPanel                     matlab.ui.container.Panel
        LoadfileButton                matlab.ui.control.Button
        LabelledtrajectoriesSwitchLabel  matlab.ui.control.Label
        LabelledtrajectoriesSwitch    matlab.ui.control.Switch
        UnlabelledtrajectoriesSwitchLabel  matlab.ui.control.Label
        UnlabelledtrajectoriesSwitch  matlab.ui.control.Switch
        DisplayPanel                  matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
        GithubTSS22Label              matlab.ui.control.Label
        ContactlucienrobinaultprotonmailcomLabel  matlab.ui.control.Label
        OptionsPanel                  matlab.ui.container.Panel
        AddcustomelementsButton       matlab.ui.control.Button
        CustomelementsSwitchLabel     matlab.ui.control.Label
        CustomelementsSwitch          matlab.ui.control.Switch
        CustomListBox                 matlab.ui.control.ListBox
        DeleteCustomButton            matlab.ui.control.Button
        Slider                        matlab.ui.control.Slider
        SamplesLabel                  matlab.ui.control.Label
    end

    
    properties (Access = private)
        % Initialize variables
        labelledTrajectories = [];
        unlabelledTrajectories = [];
        customTrajectories = {};
        customVec = {};
        
        plotLabelledTrajectories= [];
        plotUnlabelledTrajectories = [];
        plotCustomTrajectories = {};
        plotCustomVecX = {};
        plotCustomVecY = {};
        plotCustomVecZ = {};
        
        nbSamples = [];
        nbLabelled = [];
        nbUnlabbeled = [];
        nbCustom = 0;
        lastCustomDraw = -1;
        
        fieldsToGet = {'Frames','Trajectories'}; % Used for dynamic allocation
        trajectoriesType = {'Labeled  ','Unidentified'}; % Used for dynamic allocation
        
        customVariable = {'origin','vectorX','vectorY','vectorZ'};
        
        tbAxis = [];
    end
    
    methods (Access = private)
        
        function graphicElementManagement(app,varargin)
            if ~isempty(varargin)
                positionSlider = round(varargin{1});
            else
                positionSlider = round(app.Slider.Value);
            end
            
            % Clear the axes before printing again the needed trajectories
            % depending on the switch values.
            % This technique has been chosen instead of a
            % delete/.Visibility='off' because for unknown reason the
            % variable where scatter3/quiver3 data are stored is
            % cleaned/deleted randomly, which cause the app display to stop
            % working.
            % The real time display when moving the slider is therefore a
            % bit slow.
            cla(app.UIAxes)
            
            % I am using hold() in a way that the display work. It is
            % surely suboptimal but I didn't wanted to put too much time in
            % the developpment of this app.
            
            % Print
            % Labelled trajectories
            if ~isempty(app.labelledTrajectories)
                if strcmp(app.LabelledtrajectoriesSwitch.Value,'On') && app.nbLabelled>0
                    
                    app.plotLabelledTrajectories = scatter3(app.UIAxes,app.labelledTrajectories.Data(:,1,positionSlider),app.labelledTrajectories.Data(:,2,positionSlider),app.labelledTrajectories.Data(:,3,positionSlider),12,'b', 'filled');
                    hold(app.UIAxes,'on');
                end
            end
            
            % Unlabelled trajectories
            if ~isempty(app.unlabelledTrajectories)
                if strcmp(app.UnlabelledtrajectoriesSwitch.Value,'On') && app.nbUnlabbeled>0
                    
                    app.plotUnlabelledTrajectories = scatter3(app.UIAxes,app.unlabelledTrajectories.Data(:,1,positionSlider),app.unlabelledTrajectories.Data(:,2,positionSlider),app.unlabelledTrajectories.Data(:,3,positionSlider),12,'r', 'filled');
                    hold(app.UIAxes,'on');
                end
            end
            
            % Custom elements
            if strcmp(app.CustomelementsSwitch.Value,'On') && app.nbCustom>0
                for i = 1:1:app.nbCustom
                    % Custom trajectories
                    app.plotCustomTrajectories = scatter3(app.UIAxes,app.CustomListBox.ItemsData{i}.origin(1,positionSlider),app.CustomListBox.ItemsData{i}.origin(2,positionSlider),app.CustomListBox.ItemsData{i}.origin(3,positionSlider),12,'g', 'filled');
                    hold(app.UIAxes,'on');
                    % If exists, vector X of the LCS
                    if isfield(app.CustomListBox.ItemsData{i},'vectorX')
                        %
                        app.plotCustomVecX = quiver3(app.UIAxes,app.CustomListBox.ItemsData{i}.origin(1,positionSlider),...
                            app.CustomListBox.ItemsData{i}.origin(2,positionSlider),...
                            app.CustomListBox.ItemsData{i}.origin(3,positionSlider),...
                            app.CustomListBox.ItemsData{i}.vectorX(1,positionSlider),...
                            app.CustomListBox.ItemsData{i}.vectorX(2,positionSlider),...
                            app.CustomListBox.ItemsData{i}.vectorX(3,positionSlider),...
                            'r');
                        hold(app.UIAxes,'on');
                    end
                    % If exists, vector X of the LCS
                    if isfield(app.CustomListBox.ItemsData{i},'vectorY')
                        app.plotCustomVecY = quiver3(app.UIAxes,app.CustomListBox.ItemsData{i}.origin(1,round(positionSlider)),...
                            app.CustomListBox.ItemsData{i}.origin(2,positionSlider),...
                            app.CustomListBox.ItemsData{i}.origin(3,positionSlider),...
                            app.CustomListBox.ItemsData{i}.vectorY(1,positionSlider),...
                            app.CustomListBox.ItemsData{i}.vectorY(2,positionSlider),...
                            app.CustomListBox.ItemsData{i}.vectorY(3,positionSlider),...
                            'g');
                        hold(app.UIAxes,'on');
                    end
                    % If exists, vector X of the LCS
                    if isfield(app.CustomListBox.ItemsData{i},'vectorZ')
                        app.plotCustomVecZ = quiver3(app.UIAxes,app.CustomListBox.ItemsData{i}.origin(1,round(positionSlider)),...
                            app.CustomListBox.ItemsData{i}.origin(2,positionSlider),...
                            app.CustomListBox.ItemsData{i}.origin(3,positionSlider),...
                            app.CustomListBox.ItemsData{i}.vectorZ(1,positionSlider),...
                            app.CustomListBox.ItemsData{i}.vectorZ(2,positionSlider),...
                            app.CustomListBox.ItemsData{i}.vectorZ(3,positionSlider),...
                            'b');
                        hold(app.UIAxes,'on');
                    end
                end
            end
            hold(app.UIAxes,'off');
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.DisplayPanel.Title = "Choose a file with the 'load file' button";
            app.UIFigure.Name = 'MATLAB Qualisys Viewer';
            app.UIAxes.Title = [];
        end

        % Button pushed function: LoadfileButton
        function LoadfileButtonPushed(app, event)
            % Get the qualisys .mat file
            [file,path] = uigetfile('*.mat');
            if isequal(file,0)
               app.DisplayPanel.Title = "Choose a file with the 'load file' button";
            else
                app.DisplayPanel.Title = [path file];
                fileData = load(fullfile(path,file)); % Load file data
                nameStruct = fieldnames(fileData); % Get the name of the struct from the qualisys file
                
                app.nbSamples = getfield(fileData, nameStruct{1}, app.fieldsToGet{1}); % Get the number of samples
                app.labelledTrajectories = getfield(fileData, nameStruct{1}, app.fieldsToGet{2},app.trajectoriesType{1}); % Get labelled trajectories data
                app.unlabelledTrajectories = getfield(fileData, nameStruct{1}, app.fieldsToGet{2},app.trajectoriesType{2}); % Get the unlabelled trajectories data
                
                app.Slider.Limits = [1, app.nbSamples]; % Refresh the slider size
                
                % Update the trajectories count
                app.nbLabelled = app.labelledTrajectories.Count;
                app.nbUnlabbeled = app.unlabelledTrajectories.Count;
                
                % Fix the label of the axis by taking 1.1*maxValueTrajectories
                if app.nbLabelled > 0
                    limLabel = max(max(max(app.labelledTrajectories.Data)));
                else
                    limLabel = 0;
                end
                
                if app.nbUnlabbeled > 0
                    limUnlabel = max(max(max(app.unlabelledTrajectories.Data)));
                else
                    limUnlabel = 0;
                end
                
                limAxis = 1.1*max([limLabel,limUnlabel]);
                axis(app.UIAxes,[-limAxis limAxis -limAxis limAxis 0 limAxis]);
                grid(app.UIAxes,'on');
                
                % Print the labelled trajectories of the files by starting an event "value change" on the labelled trajectories switch
                app.LabelledtrajectoriesSwitch.Value = 'On';
                event = matlab.ui.eventdata.ValueChangedData('On', 'Off');
                LabelledtrajectoriesSwitchValueChanged(app, event);
            end


        end

        % Value changed function: LabelledtrajectoriesSwitch
        function LabelledtrajectoriesSwitchValueChanged(app, event)
            graphicElementManagement(app);
        end

        % Value changed function: UnlabelledtrajectoriesSwitch
        function UnlabelledtrajectoriesSwitchValueChanged(app, event)
            graphicElementManagement(app);
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            graphicElementManagement(app,event.Value);
        end

        % Button pushed function: DeleteCustomButton
        function DeleteCustomButtonPushed(app, event)
            % Delete the selected element from the list box of custom
            % elements
            if ~isempty(app.CustomListBox.Items)
                for i = 1:1:length(app.CustomListBox.ItemsData)
                    if isequaln(app.CustomListBox.Value,app.CustomListBox.ItemsData{i})
                        idx = i;
                        app.CustomListBox.Items(idx) = [];
                        app.CustomListBox.ItemsData(idx) = [];
                        app.nbCustom = app.nbCustom-1;
                    end
                end
            end
        end

        % Button pushed function: AddcustomelementsButton
        function AddcustomelementsButtonPushed(app, event)
            % The file need to have origin for the LCS origin trajectory and
            % vectorX, vectorY, vectorZ for the trajectories of the vectors of the LCS X, Y, Z
            [file,path] = uigetfile('*.mat');
            if ~isequal(file,0)
                fileData = load(fullfile(path,file)); % Load file data
                nameStruct = fieldnames(fileData); % Get the name of the struct from the custom file
                
                % Populate the list box
                app.CustomListBox.Items{end+1} = nameStruct{1};
                app.CustomListBox.ItemsData{end+1} = fileData.(nameStruct{1});
                
                % Increase the counter of custome elements
                app.nbCustom = app.nbCustom+1;
                
                graphicElementManagement(app);
            end
            
        end

        % Value changed function: CustomelementsSwitch
        function CustomelementsSwitchValueChanged(app, event)
            graphicElementManagement(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.MenuPanel = uipanel(app.UIFigure);
            app.MenuPanel.Title = 'Menu';
            app.LoadfileButton = uibutton(app.MenuPanel, 'push');
            app.LoadfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfileButtonPushed, true);
            app.LoadfileButton.Text = 'Load file';
            app.LabelledtrajectoriesSwitchLabel = uilabel(app.MenuPanel);
            app.LabelledtrajectoriesSwitchLabel.Text = 'Labelled trajectories';
            app.LabelledtrajectoriesSwitch = uiswitch(app.MenuPanel, 'slider');
            app.LabelledtrajectoriesSwitch.ValueChangedFcn = createCallbackFcn(app, @LabelledtrajectoriesSwitchValueChanged, true);
            app.UnlabelledtrajectoriesSwitchLabel = uilabel(app.MenuPanel);
            app.UnlabelledtrajectoriesSwitchLabel.Text = 'Unlabelled trajectories';
            app.UnlabelledtrajectoriesSwitch = uiswitch(app.MenuPanel, 'slider');
            app.UnlabelledtrajectoriesSwitch.ValueChangedFcn = createCallbackFcn(app, @UnlabelledtrajectoriesSwitchValueChanged, true);
            app.DisplayPanel = uipanel(app.UIFigure);
            app.DisplayPanel.Title = 'Display';
            app.UIAxes = uiaxes(app.DisplayPanel);
            title(app.UIAxes, '''''')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.GithubTSS22Label = uilabel(app.UIFigure);
            app.GithubTSS22Label.Text = 'Github: TSS-22';
            app.ContactlucienrobinaultprotonmailcomLabel = uilabel(app.UIFigure);
            app.ContactlucienrobinaultprotonmailcomLabel.Text = 'Contact: lucien.robinault@protonmail.com';
            app.OptionsPanel = uipanel(app.UIFigure);
            app.OptionsPanel.Title = 'Options';
            app.AddcustomelementsButton = uibutton(app.OptionsPanel, 'push');
            app.AddcustomelementsButton.ButtonPushedFcn = createCallbackFcn(app, @AddcustomelementsButtonPushed, true);
            app.AddcustomelementsButton.Text = 'Add custom elements';
            app.CustomelementsSwitchLabel = uilabel(app.OptionsPanel);
            app.CustomelementsSwitchLabel.Text = 'Custom elements';
            app.CustomelementsSwitch = uiswitch(app.OptionsPanel, 'slider');
            app.CustomelementsSwitch.ValueChangedFcn = createCallbackFcn(app, @CustomelementsSwitchValueChanged, true);
            app.CustomListBox = uilistbox(app.OptionsPanel);
            app.CustomListBox.Items = {};
            app.CustomListBox.Value = {};
            app.DeleteCustomButton = uibutton(app.OptionsPanel, 'push');
            app.DeleteCustomButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteCustomButtonPushed, true);
            app.DeleteCustomButton.Text = 'Delete';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [1 100];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.Value = 1;
            app.SamplesLabel = uilabel(app.UIFigure);
            app.SamplesLabel.Text = 'Samples';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = qualisysMatViewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end